classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        imageAx            matlab.ui.control.UIAxes
        FrameSpinnerLabel  matlab.ui.control.Label
        FrameSpinner       matlab.ui.control.Spinner
        DoneButton         matlab.ui.control.Button
        Slider             matlab.ui.control.Slider
        FrameRate          matlab.ui.control.Label
        Lamp               matlab.ui.control.Lamp
        WritingButton      matlab.ui.control.StateButton
        Label_2            matlab.ui.control.Label
        Frame_Step_Field   matlab.ui.control.NumericEditField
        Label_3            matlab.ui.control.Label
        Joint_Num_Field    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        vidObj % Description]
        cdata
        TotalFrame
        id_image
        SizeImage_V
        SizeImage_H
    end
    
    methods (Access = public)
        
        function setImage(app, value)
            changedValue = round(value);
            app.Slider.Value =  changedValue;
            app.FrameSpinner.Value = changedValue;
            
            app.Lamp.Color = [1.00,0.00,0.00];
            
            time_frame = round(changedValue);
            app.vidObj.CurrentTime = 1/app.vidObj.FrameRate*(time_frame);
%             app.vidObj.CurrentTime
            
%             cdata_tmp = readFrame(app.vidObj);
%             EnhanceImage = 1;
%             app.cdata = uint8(EnhanceImage * cdata_tmp);
            
            app.cdata = readFrame(app.vidObj);

            app.id_image.CData = app.cdata;
            drawnow
            
%             app.imageState.Text = "loaded";
            app.Lamp.Color = [0.00,1.00,0.00];
            
%             if app.WritingButton.Value
%                 for ii = 1:app.Joint_Num_Field.Value
%                     
%                 end
%             end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filename, pathname)
            app.vidObj = VideoReader(strcat(pathname,filename));
            app.vidObj.CurrentTime = 0;
            app.cdata = readFrame(app.vidObj);
            
            app.FrameRate.Text = strcat("FrameRate:",num2str(app.vidObj.FrameRate));
            
            app.TotalFrame = round(app.vidObj.Duration*app.vidObj.FrameRate);
            
            app.id_image = image(app.imageAx, app.cdata);
            
            app.SizeImage_V=length(app.cdata(:,1,1));
            app.SizeImage_H=length(app.cdata(1,:,1));
            
            pbaspect(app.imageAx, [app.SizeImage_H, app.SizeImage_V, 1])
            
            xlim(app.imageAx, [0, app.SizeImage_H])
            ylim(app.imageAx, [0, app.SizeImage_V])
            
%             app.imageAx.XDir = "normal";
%             app.imageAx.YDir = "normal";
            
            app.Slider.Limits = [0, app.TotalFrame];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            setImage(app, event.Value)
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            setImage(app, event.Value)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
%             if app.WritingButton.Value
%                 if (event.Source.CurrentPoint(1,1) >= app.imageAx.InnerPosition(1,1) &&...
%                         event.Source.CurrentPoint(1,1) <= app.imageAx.InnerPosition(1,1) + app.imageAx.InnerPosition(1,3))
%                     if (event.Source.CurrentPoint(1,2) >= app.imageAx.InnerPosition(1,2) &&...
%                             event.Source.CurrentPoint(1,2) <= app.imageAx.InnerPosition(1,2) + app.imageAx.InnerPosition(1,4))
%                         
%                         xsc_pixel = event.Source.CurrentPoint(1,1) - app.imageAx.InnerPosition(1,1);
%                         xsc_onAx = xsc_pixel / app.imageAx.InnerPosition(1,3) * diff(app.imageAx.XLim) + app.imageAx.XLim(1,1);
%                         
%                         ysc_pixel = event.Source.CurrentPoint(1,2) - app.imageAx.InnerPosition(1,2);
%                         ysc_onAx = -ysc_pixel / app.imageAx.InnerPosition(1,4) * diff(app.imageAx.YLim) + app.imageAx.YLim(1,2);
%                         
%                         event.Source.CurrentPoint
%                         
% %                         hold(app.imageAx,"on")
% %                         scatter(app.imageAx, xsc_onAx, ysc_onAx, 100, "r+")
% %                         hold(app.imageAx,"off")
%                     end
%                 end
%             end
            
            event.Source.CurrentPoint
            
%             if ~isempty(event.Source.CurrentObject.Parent)
%                 if (isequal(event.Source.CurrentObject.Parent, app.imageAx) && app.WritingButton.Value)
%                     xsc_pixel = event.Source.CurrentPoint(1,1) - app.imageAx.Position(1,1);
%                     xsc_onAx = xsc_pixel / app.imageAx.Position(1,3) * diff(app.imageAx.XLim) + app.imageAx.XLim(1,1);
%                     
%                     ysc_pixel = event.Source.CurrentPoint(1,2) - app.imageAx.Position(1,2);
%                     ysc_onAx = -ysc_pixel / app.imageAx.Position(1,4) * diff(app.imageAx.YLim) + app.imageAx.YLim(1,2);
%                     
%                     hold(app.imageAx,"on")
%                     scatter(app.imageAx, xsc_onAx, ysc_onAx, 100, "r+")
%                     hold(app.imageAx,"off")
%                 end
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.imageAx = uiaxes(app.UIFigure);
            title(app.imageAx, 'Image')
            xlabel(app.imageAx, '')
            ylabel(app.imageAx, '')
            app.imageAx.XLim = [0 1];
            app.imageAx.YLim = [0 1];
            app.FrameSpinnerLabel = uilabel(app.UIFigure);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.UIFigure);
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Text = 'Done';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FrameRate = uilabel(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            app.WritingButton = uibutton(app.UIFigure, 'state');
            app.WritingButton.Text = 'Writing';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '何フレーム毎';
            app.Frame_Step_Field = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '関節数';
            app.Joint_Num_Field = uieditfield(app.UIFigure, 'numeric');
            app.Joint_Num_Field.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end